classdef Calc_LT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % Cálculo ...
        TabGroup              matlab.ui.container.TabGroup       % Ingresar...
        CalculoParametros     matlab.ui.container.Tab            % Ingresar...
        Label                 matlab.ui.control.Label            % Cálculo ...
        DatosEntrada          matlab.ui.container.Panel          % Datos de...
        Panel                 matlab.ui.container.Panel          % General
        LabelDropDown         matlab.ui.control.Label            % Tipo de ...
        TipoConductor         matlab.ui.control.DropDown         % Partridg...
        LabelNumericEditField matlab.ui.control.Label            % Longitud...
        LongitudLinea         matlab.ui.control.NumericEditField % [0 1000]
        Label3                matlab.ui.control.Label            % Tensión ...
        TensionOperacion      matlab.ui.control.NumericEditField % [0 10000...
        Label6                matlab.ui.control.Label            % Potencia...
        PotenciaCarga         matlab.ui.control.NumericEditField % [0 10000...
        Label7                matlab.ui.control.Label            % Factor d...
        FactorPotencia        matlab.ui.control.NumericEditField % [0 1]
        Label8                matlab.ui.control.Label            % Temp. de...
        TempOp                matlab.ui.control.NumericEditField % [0 150]
        DistribucionPanel     matlab.ui.container.Panel          % Distribu...
        Label2                matlab.ui.control.Label            % Distribu...
        Distribucion          matlab.ui.control.DropDown         % Cuadrada...
        Label9                matlab.ui.control.Label            % Distanci...
        Distancia             matlab.ui.control.NumericEditField % [0 50]
        RLC                   matlab.ui.container.Panel          % Parámetr...
        LabelTextArea         matlab.ui.control.Label            % Resisten...
        Res                   matlab.ui.control.TextArea        
        Label4                matlab.ui.control.Label            % Inductan...
        Ind                   matlab.ui.control.TextArea        
        Label5                matlab.ui.control.Label            % Capacita...
        Cap                   matlab.ui.control.TextArea        
        BotonCalcRLC          matlab.ui.control.Button           % Calcular
        Tab2                  matlab.ui.container.Tab            % Tab 2
        Tab3                  matlab.ui.container.Tab            % Tab 3
    end


    properties (Access = private)
        SeccTrans_glob
        RMG_glob % Description
        DMG_glob % Description
        Distrib_glob % Description
        
        Long_in
        Vop_in
        Pcarga_in
        FP_in
        Temp_in
        Dist_in
        
        Res_calc
        Ind_calc
        Cap_calc
    end

    methods (Access = private)
        
        function R_ca = getRca(app, long, secc_trans, num_hilos)
            Resistividad = 17; %(Ohm*cmil)/ft
            long = long*3.28084;
            r1 = Resistividad*long/secc_trans;
            if num_hilos > 3
               r1 = r1 + r1*0.05;
            else
               r1 = r1 + r1*0.04;
            end
            R_ca = r1;
        end
        
        function R_L = tempFunctionRes(app, R1, t1, t2)
            T = 228; %°C, para conductores de aluminio estirado
            R_L = R1*(T+t2)/(T+t1);
        end
        
        function DMG = DMG_square(app, D)
            a = D*D*sqrt(2*D^2);
            DMG = nthroot(a,3);
        end
        
        function DMG = DMG_equilat(app, D)
            DMG = D;
        end
        
        function DMG = DMG_straight(app, D)
            a = 2*D*D*D;
            DMG = nthroot(a,3);
        end
        
        function DMG = DMGbyDistribution(app, D, dis)
            a = 0;
            switch dis
                case 1
                    a = DMG_square(app, D);
                case 2
                    a = DMG_equilat(app, D);
                case 3
                    a = DMG_straight(app, D);
                case 4
                    a = DMG_straight(app, D);
                otherwise
                    a = 0;
            end
            DMG = a;
        end

        function XL_L = lineInductiveReactance(app, f, d, DMG_L, RMG_L)
            l = (2*10^(-7))*log(DMG_L/RMG_L);   %inductividad por unidad de longitud en H/m
            L = l*d;                            %inductividad de la línea total en H
            XL_L = [2*pi*f*L, L];               %Reactancia de la línea en Ohms
        end
        
        function BC_L = lineCapacitiveSusceptance(app, f, d, DMG_C, RMG_C)
            c = (2*pi*(8.85*10^(-12)))/(log(DMG_C/RMG_C));  %capacitancia por unidad de longitud en F/m
            C = c*d;                                   %capacitancia de la línea total en F
            BC_L = [2*pi*f*C, C];                           %Susceptancia de la línea en Ohms
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RMG_glob = 0;
            app.DMG_glob = 0;
            app.Distrib_glob = 0;
            app.SeccTrans_glob = 0;
            
            app.Long_in = 0;
            app.Vop_in = 0;
            app.Pcarga_in = 0;
            app.FP_in = 0;
            app.Temp_in = 0;
            app.Dist_in = 0;
            
            app.Res_calc = 0;
            app.Ind_calc = 0;
            app.Cap_calc = 0;
        end

        % TipoConductor value changed function
        function TipoConductorValueChanged(app, event)
            Conductores = {'Partridge', 'Dove', 'Drake', 'Bluejay', 'Pheasant', 'Bluebird'};
            RMG_cond = [0.0217, 0.0314, 0.0373, 0.0415, 0.0466, 0.0586];
            SeccTrans_cond = [266800, 556500, 795000, 1113000, 1272000, 2156000]; %[cmil]
            app.RMG_glob = 0;
            value = app.TipoConductor.Value;
            for i=1:6
               if strcmp(value, Conductores(i))
                   app.RMG_glob = RMG_cond(i);
                   app.SeccTrans_glob = SeccTrans_cond(i);
               end
            end
            %app.Ind.Value = num2str(app.SeccTrans_glob);
        end

        % Distribucion value changed function
        function DistribucionValueChanged(app, event)
            Distribuciones = {'Cuadrada', 'Equilatera', 'Vertical', 'Horizontal'};
            app.Distrib_glob = 0;
            value = app.Distribucion.Value;
            for i=1:4
               if strcmp(value, Distribuciones(i))
                   app.Distrib_glob = i;
               end
            end
            %DMGbyDistribution(app, app.D_glob)
            %app.Ind.Value = num2str(app.Distrib);
        end

        % LongitudLinea value changed function
        function LongitudLineaValueChanged(app)
            value = app.LongitudLinea.Value;
            app.Long_in = value*1000;
        end

        % TensionOperacion value changed function
        function TensionOperacionValueChanged(app)
            value = app.TensionOperacion.Value;
            app.Vop_in = value;
        end

        % PotenciaCarga value changed function
        function PotenciaCargaValueChanged(app)
            value = app.PotenciaCarga.Value;
            app.Pcarga_in = value;
        end

        % FactorPotencia value changed function
        function FactorPotenciaValueChanged(app)
            value = app.FactorPotencia.Value;
            app.FP_in = value;
        end

        % TempOp value changed function
        function TempOpValueChanged(app)
            value = app.TempOp.Value;
            app.Temp_in = value;
        end

        % Distancia value changed function
        function DistanciaValueChanged(app)
            value = app.Distancia.Value;
            app.Dist_in = value;
        end

        % BotonCalcRLC button pushed function
        function BotonCalcRLCButtonPushed(app)
            app.Res_calc = tempFunctionRes(app, getRca(app, app.Long_in, app.SeccTrans_glob, 100), 20, app.Temp_in);
            app.Res.Value = num2str(app.Res_calc);
            
            app.DMG_glob = DMGbyDistribution(app, app.Dist_in, app.Distrib_glob);

            L = lineInductiveReactance(app, 60, app.Long_in, app.DMG_glob, app.RMG_glob);
            app.Ind_calc = L(2);
            app.Ind.Value = num2str(app.Ind_calc);
            
            C = lineCapacitiveSusceptance(app, 60, app.Long_in, app.DMG_glob, app.RMG_glob);
            app.Cap_calc = C(2);
            app.Cap.Value = num2str(app.Cap_calc);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Cálculo de Parámetros';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.CalculoParametros = uitab(app.TabGroup);
            app.CalculoParametros.Units = 'pixels';
            app.CalculoParametros.Title = 'Ingresar Datos';
            app.Label = uilabel(app.CalculoParametros);
            app.Label.Text = 'Cálculo de parámetros de Líneas de Transmisión';
            app.DatosEntrada = uipanel(app.CalculoParametros);
            app.DatosEntrada.Title = 'Datos de entrada';
            app.DatosEntrada.Units = 'pixels';
            app.Panel = uipanel(app.DatosEntrada);
            app.Panel.Title = 'General';
            app.Panel.Units = 'pixels';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Tipo de conductor';
            app.TipoConductor = uidropdown(app.Panel);
            app.TipoConductor.Items = {'Partridge', 'Drake', 'Bluejay', 'Bluebird', 'Dove', 'Pheasant'};
            app.TipoConductor.ValueChangedFcn = createCallbackFcn(app, @TipoConductorValueChanged, true);
            app.TipoConductor.Value = 'Partridge';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Longitud de la línea [km]';
            app.LongitudLinea = uieditfield(app.Panel, 'numeric');
            app.LongitudLinea.ValueChangedFcn = createCallbackFcn(app, @LongitudLineaValueChanged);
            app.LongitudLinea.Limits = [0 1000];
            app.LongitudLinea.ValueDisplayFormat = '%5.2f';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Tensión de operación [kV]';
            app.TensionOperacion = uieditfield(app.Panel, 'numeric');
            app.TensionOperacion.ValueChangedFcn = createCallbackFcn(app, @TensionOperacionValueChanged);
            app.TensionOperacion.Limits = [0 1000000000];
            app.TensionOperacion.ValueDisplayFormat = '%5.2f';
            app.Label6 = uilabel(app.Panel);
            app.Label6.Text = 'Potencia de la carga [W]';
            app.PotenciaCarga = uieditfield(app.Panel, 'numeric');
            app.PotenciaCarga.ValueChangedFcn = createCallbackFcn(app, @PotenciaCargaValueChanged);
            app.PotenciaCarga.Limits = [0 1000000000];
            app.PotenciaCarga.ValueDisplayFormat = '%5.2f';
            app.Label7 = uilabel(app.Panel);
            app.Label7.Text = 'Factor de Potencia';
            app.FactorPotencia = uieditfield(app.Panel, 'numeric');
            app.FactorPotencia.ValueChangedFcn = createCallbackFcn(app, @FactorPotenciaValueChanged);
            app.FactorPotencia.Limits = [0 1];
            app.FactorPotencia.ValueDisplayFormat = '%1.2f';
            app.Label8 = uilabel(app.Panel);
            app.Label8.Text = 'Temp. de operación [°C]';
            app.TempOp = uieditfield(app.Panel, 'numeric');
            app.TempOp.ValueChangedFcn = createCallbackFcn(app, @TempOpValueChanged);
            app.TempOp.Limits = [0 150];
            app.TempOp.ValueDisplayFormat = '%1.2f';
            app.DistribucionPanel = uipanel(app.DatosEntrada);
            app.DistribucionPanel.Title = 'Distribución';
            app.DistribucionPanel.Units = 'pixels';
            app.Label2 = uilabel(app.DistribucionPanel);
            app.Label2.Text = 'Distribución';
            app.Distribucion = uidropdown(app.DistribucionPanel);
            app.Distribucion.Items = {'Cuadrada', 'Equilatera', 'Vertical', 'Horizontal'};
            app.Distribucion.ValueChangedFcn = createCallbackFcn(app, @DistribucionValueChanged, true);
            app.Distribucion.Value = 'Cuadrada';
            app.Label9 = uilabel(app.DistribucionPanel);
            app.Label9.Text = 'Distancia [m]';
            app.Distancia = uieditfield(app.DistribucionPanel, 'numeric');
            app.Distancia.ValueChangedFcn = createCallbackFcn(app, @DistanciaValueChanged);
            app.Distancia.Limits = [0 50];
            app.Distancia.ValueDisplayFormat = '%1.2f';
            app.RLC = uipanel(app.CalculoParametros);
            app.RLC.Title = 'Parámetros R, L y C';
            app.RLC.Units = 'pixels';
            app.LabelTextArea = uilabel(app.RLC);
            app.LabelTextArea.Text = 'Resistencia [Ohms]';
            app.Res = uitextarea(app.RLC);
            app.Res.Editable = 'off';
            app.Label4 = uilabel(app.RLC);
            app.Label4.Text = 'Inductancia [H]';
            app.Ind = uitextarea(app.RLC);
            app.Ind.Editable = 'off';
            app.Label5 = uilabel(app.RLC);
            app.Label5.Text = 'Capacitancia [F]';
            app.Cap = uitextarea(app.RLC);
            app.Cap.Editable = 'off';
            app.BotonCalcRLC = uibutton(app.RLC, 'push');
            app.BotonCalcRLC.ButtonPushedFcn = createCallbackFcn(app, @BotonCalcRLCButtonPushed);
            app.BotonCalcRLC.Icon = 'LT.jpg';
            app.BotonCalcRLC.Text = 'Calcular';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Tab 2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Tab 3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Calc_LT()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end